classdef Advanced < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        rmaxmodeDropDown        matlab.ui.control.DropDown
        rmaxmodeDropDownLabel   matlab.ui.control.Label
        tresholdEditField       matlab.ui.control.NumericEditField
        tresholdEditFieldLabel  matlab.ui.control.Label
        rmaxEditField           matlab.ui.control.NumericEditField
        rmaxEditFieldLabel      matlab.ui.control.Label
        rpointsEditField        matlab.ui.control.NumericEditField
        rpointsEditFieldLabel   matlab.ui.control.Label
        pointsEditField         matlab.ui.control.NumericEditField
        pointsEditFieldLabel    matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
    end
    
    properties (Access = private)
        ImgType
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, rmaxmode, threshold, rmax, rpoints, points, ImgType)
            ScreenSize = get(0, 'ScreenSize');
            app.UIFigure.Position(1) = (ScreenSize(3)-app.UIFigure.Position(3))/2;
            app.UIFigure.Position(2) = (ScreenSize(4)-app.UIFigure.Position(4))/2;
            
            app.CallingApp = mainapp;
            app.rmaxmodeDropDown.Value = rmaxmode;
            app.tresholdEditField.Value = threshold;
            app.rmaxEditField.Value = rmax;
            app.rpointsEditField.Value = rpoints;
            app.pointsEditField.Value = points;

            if strcmp(ImgType, '径向函数') || strcmp(ImgType, '径向概率密度')
                app.rpointsEditField.Visible = 'off';
                app.rpointsEditFieldLabel.Visible = 'off';
                app.pointsEditField.Visible = 'off';
                app.pointsEditFieldLabel.Visible = 'off';
            elseif strcmp(ImgType, '电子云图')
                app.rpointsEditField.Visible = 'off';
                app.rpointsEditFieldLabel.Visible = 'off';
                app.pointsEditField.Visible = 'on';
                app.pointsEditFieldLabel.Visible = 'on';
            else
                app.rpointsEditField.Visible = 'on';
                app.rpointsEditFieldLabel.Visible = 'on';
                app.pointsEditField.Visible = 'off';
                app.pointsEditFieldLabel.Visible = 'off';
            end
            if strcmp(app.rmaxmodeDropDown.Value,'Auto')
                app.tresholdEditField.Enable = 'on';
                app.rmaxEditField.Enable = 'off';
            else
                app.tresholdEditField.Enable = 'off';
                app.rmaxEditField.Enable = 'on';
            end
        end

        % Value changed function: tresholdEditField
        function tresholdEditFieldValueChanged(app, event)
            if app.tresholdEditField.Value <= 0 || app.tresholdEditField.Value >= 1
                uialert(app.UIFigure,'treshold应为0到1之间的数！','Error')
                app.tresholdEditField.Value = 0.01;
            end
        end

        % Value changed function: rmaxEditField
        function rmaxEditFieldValueChanged(app, event)
            if app.rmaxEditField.Value < 1
                uialert(app.UIFigure,'rmax应为正整数！','Error')
                app.rmaxEditField.Value = 1;
            elseif app.rmaxEditField.Value ~= round(app.rmaxEditField.Value)
                uialert(app.UIFigure,'rmax应为正整数！','Error')
                app.rmaxEditField.Value = round(app.rmaxEditField.Value);
            end
        end

        % Value changed function: rpointsEditField
        function rpointsEditFieldValueChanged(app, event)
            if app.rpointsEditField.Value < 1
                uialert(app.UIFigure,'rpoints应为正整数！','Error')
                app.rpointsEditField.Value = 1;
            elseif app.rpointsEditField.Value ~= round(app.rpointsEditField.Value)
                uialert(app.UIFigure,'rpoints应为正整数！','Error')
                app.rpointsEditField.Value = round(app.rpointsEditField.Value);
            end   
        end

        % Value changed function: pointsEditField
        function pointsEditFieldValueChanged(app, event)
            if app.pointsEditField.Value < 1
                uialert(app.UIFigure,'points应为正整数！','Error')
                app.pointsEditField.Value = 1;
            elseif app.pointsEditField.Value ~= round(app.pointsEditField.Value)
                uialert(app.UIFigure,'points应为正整数！','Error')
                app.pointsEditField.Value = round(app.pointsEditField.Value);
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            AdvancedOptions(app.CallingApp, app.rmaxmodeDropDown.Value, app.tresholdEditField.Value, app.rmaxEditField.Value, app.rpointsEditField.Value, app.pointsEditField.Value)
            app.CallingApp.AdvButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AdvButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: rmaxmodeDropDown
        function rmaxmodeDropDownValueChanged(app, event)
            if strcmp(app.rmaxmodeDropDown.Value, 'Auto')
                app.tresholdEditField.Enable = 'on';
                app.rmaxEditField.Enable = 'off';
            else
                app.tresholdEditField.Enable = 'off';
                app.rmaxEditField.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = '确认';
            app.pointsEditFieldLabel = uilabel(app.UIFigure);
            app.pointsEditFieldLabel.Tooltip = {'电子云图的初始点数'};
            app.pointsEditFieldLabel.Text = 'points';
            app.pointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.pointsEditField.ValueChangedFcn = createCallbackFcn(app, @pointsEditFieldValueChanged, true);
            app.pointsEditField.Tooltip = {''};
            app.pointsEditField.Value = 10000;
            app.rpointsEditFieldLabel = uilabel(app.UIFigure);
            app.rpointsEditFieldLabel.Tooltip = {'每个坐标轴上取的点数'};
            app.rpointsEditFieldLabel.Text = 'rpoints';
            app.rpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.rpointsEditField.ValueChangedFcn = createCallbackFcn(app, @rpointsEditFieldValueChanged, true);
            app.rpointsEditField.Tooltip = {''};
            app.rpointsEditField.Value = 50;
            app.rmaxEditFieldLabel = uilabel(app.UIFigure);
            app.rmaxEditFieldLabel.Tooltip = {'坐标轴的最大值'};
            app.rmaxEditFieldLabel.Text = 'rmax';
            app.rmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.rmaxEditField.ValueChangedFcn = createCallbackFcn(app, @rmaxEditFieldValueChanged, true);
            app.rmaxEditField.Tooltip = {'坐标轴的边界值'};
            app.rmaxEditField.Value = 5;
            app.tresholdEditFieldLabel = uilabel(app.UIFigure);
            app.tresholdEditFieldLabel.Tooltip = {'最小径向概率与最大径向概率的比值，决定了自动计算坐标轴边界值的大小'};
            app.tresholdEditFieldLabel.Text = 'treshold';
            app.tresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.tresholdEditField.ValueChangedFcn = createCallbackFcn(app, @tresholdEditFieldValueChanged, true);
            app.tresholdEditField.Tooltip = {''};
            app.tresholdEditField.Value = 0.01;
            app.rmaxmodeDropDownLabel = uilabel(app.UIFigure);
            app.rmaxmodeDropDownLabel.Tooltip = {'选择自动计算/手动输入坐标轴边界值'};
            app.rmaxmodeDropDownLabel.Text = 'rmaxmode';
            app.rmaxmodeDropDown = uidropdown(app.UIFigure);
            app.rmaxmodeDropDown.Items = {'Auto', 'Customize'};
            app.rmaxmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @rmaxmodeDropDownValueChanged, true);
            app.rmaxmodeDropDown.Tooltip = {''};
            app.rmaxmodeDropDown.Value = 'Auto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Advanced(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end