classdef Hygrogen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        ParamPanel       matlab.ui.container.Panel
        BasicParamPanel  matlab.ui.container.Panel
        ImgTypeDropDown  matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        nSpinner         matlab.ui.control.Spinner
        nSpinnerLabel    matlab.ui.control.Label
        lSpinner         matlab.ui.control.Spinner
        lSpinnerLabel    matlab.ui.control.Label
        mSpinner         matlab.ui.control.Spinner
        mSpinnerLabel    matlab.ui.control.Label
        ZSpinner         matlab.ui.control.Spinner
        ZSpinnerLabel    matlab.ui.control.Label
        OptParamPanel    matlab.ui.container.Panel
        IntrEditField    matlab.ui.control.NumericEditField
        EqTextArea       matlab.ui.control.TextArea
        AxesDropDown     matlab.ui.control.DropDown
        SliceCheckBox    matlab.ui.control.CheckBox
        ContourCheckBox  matlab.ui.control.CheckBox
        AdvButton        matlab.ui.control.Button
        PlotButton       matlab.ui.control.Button
        ImgPanel         matlab.ui.container.Panel
        SaveButton       matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        rmaxmode = 'Auto'
        treshold = 0.01
        rmax = 1
        rpoints = 100
        points = 10000
    end

    properties (Access = private)
        DialogApp
        n
        l
        m
        Z
    end

    methods (Access = public)
        
        function AdvancedOptions(app,newraxmode,newthreshold,newrmax,newrpoints,newpoints)
            app.rmaxmode = newraxmode;
            app.treshold = newthreshold;
            app.rmax = newrmax;
            app.rpoints = newrpoints;
            app.points = newpoints;
        end
    end

    methods (Access = private)
        
        function PlotRadialFunction(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            r = 0:0.1:app.rmax;
            Rnl = RadialFunction(app.n,app.l,app.Z,r);
            plot(app.UIAxes,r,Rnl,'LineWidth',1);
            set(app.UIAxes,'XAxisLocation','origin')
            title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' Z=',num2str(app.Z),' 径向函数'])
            xlabel(app.UIAxes,'r/a_0')
            ylabel(app.UIAxes,'R_{nl}')
            close(d)
        end
 
        function PlotRadialProbability(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            r = 0:0.1:app.rmax;
            Rnl = RadialFunction(app.n,app.l,app.Z,r);
            P = conj(Rnl).*Rnl;
            plot(app.UIAxes,r,P,'LineWidth',1);
            title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' Z=',num2str(app.Z),' 径向概率分布'])
            xlabel(app.UIAxes,'r/a_0')
            ylabel(app.UIAxes,'|R_{nl}|^2')
            close(d)
        end

        function PlotRadialDensity(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            r = 0:0.1:app.rmax;
            Rnl = RadialFunction(app.n,app.l,app.Z,r);
            P = power(r,2).*conj(Rnl).*Rnl;
            plot(app.UIAxes,r,P,'LineWidth',1);
            title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' Z=',num2str(app.Z),' 径向概率密度'])
            xlabel(app.UIAxes,'r/a_0')
            ylabel(app.UIAxes,'r^2R_{nl}|^2')
            close(d)
        end

        function PlotSphericalHarmonics(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            [phi,theta] = meshgrid(0:pi/180:2*pi, 0:pi/180:pi);
            Ylm = SphericalHarmonics(app.l,app.m,phi,theta);
            [x,y,z] = sph2cart(phi, pi/2-theta, abs(real(Ylm)));
            surf(app.UIAxes,x,y,z,'EdgeColor','none')
            colormap(app.UIAxes,'summer')
            axis(app.UIAxes,'equal')
            title(app.UIAxes,['l=',num2str(app.l),' m=',num2str(app.m),' 球谐函数'])
            close(d)
        end
        
        function PlotAngularProbability(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            [phi,theta] = meshgrid(0:pi/180:2*pi, 0:pi/180:pi);
            Ylm = SphericalHarmonics(app.l,app.m,phi,theta);
            P = conj(Ylm).*Ylm;
            [x,y,z] = sph2cart(phi, pi/2-theta, P);
            surf(app.UIAxes,x,y,z,'EdgeColor','none')
            colormap(app.UIAxes,'summer')
            axis(app.UIAxes,'equal')
            title(app.UIAxes,['l=',num2str(app.l),' m=',num2str(app.m),' 角向概率分布'])
            close(d)
        end

        function PlotSlice(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            if strcmp(app.rmaxmode,'Auto') && app.l == 0
                app.rmax = 2;
            end
            dim = linspace(-app.rmax,app.rmax,app.rpoints);
            [x,y,z] = meshgrid(0,dim,dim);
            x = squeeze(x);
            y = squeeze(y);
            z = squeeze(z);
            r = sqrt(x.^2+y.^2+z.^2);
            phi = atan2(y,x);
            theta = atan2(sqrt(x.^2+y.^2),z);
            Psi = WaveFunction(app.n,app.l,app.m,app.Z,r,phi,theta);
            P = conj(Psi).*Psi;
            Pmax = max(P,[],'all');
            if strcmp(app.AxesDropDown.Value, 'x')
                [x,y,z] = meshgrid(app.IntrEditField.Value,dim,dim);
            elseif strcmp(app.AxesDropDown.Value, 'y')
                [x,y,z] = meshgrid(dim,app.IntrEditField.Value,dim);
            else
                [x,y,z] = meshgrid(dim,dim,app.IntrEditField.Value);
            end
            x = squeeze(x);
            y = squeeze(y);
            z = squeeze(z);
            r = sqrt(x.^2+y.^2+z.^2);
            phi = atan2(y,x);
            theta = atan2(sqrt(x.^2+y.^2),z);
            Psi = WaveFunction(app.n,app.l,app.m,app.Z,r,phi,theta);
            P = conj(Psi).*Psi;  
            P = P/Pmax;
            if strcmp(app.AxesDropDown.Value, 'x')
                hold(app.UIAxes,'on')
                s = pcolor(app.UIAxes,y,z,P);
                if app.ContourCheckBox.Value
                    contour(app.UIAxes,y,z,P,[0.2 0.4 0.6 0.8],'k','LineWidth',0.01);
                end
                hold(app.UIAxes,'off')
                title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' m=',num2str(app.m),' Z=',num2str(app.Z),' yz切面图'])
                xlabel(app.UIAxes,'y/a_0')
                ylabel(app.UIAxes,'z/a_0')
            elseif strcmp(app.AxesDropDown.Value, 'y')
                hold(app.UIAxes,'on')
                s = pcolor(app.UIAxes,x,z,P);
                if app.ContourCheckBox.Value
                    contour(app.UIAxes,x,z,P,[0.2 0.4 0.6 0.8],'k','LineWidth',0.01);
                end
                hold(app.UIAxes,'off')
                title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' m=',num2str(app.m),' Z=',num2str(app.Z),' xz切面图'])
                xlabel(app.UIAxes,'x/a_0')
                ylabel(app.UIAxes,'z/a_0')
            else
                hold(app.UIAxes,'on')
                s = pcolor(app.UIAxes,x,y,P);
                if app.ContourCheckBox.Value
                    contour(app.UIAxes,x,y,P,[0.2 0.4 0.6 0.8],'k','LineWidth',0.01);
                end
                hold(app.UIAxes,'off')
                title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' m=',num2str(app.m),' Z=',num2str(app.Z),' xy切面图'])
                xlabel(app.UIAxes,'x/a_0')
                ylabel(app.UIAxes,'y/a_0')
            end
            axis(app.UIAxes,'square')
            daspect(app.UIAxes,[1 1 1])
            colormap(app.UIAxes, flipud(gray))
            caxis(app.UIAxes,[0 1])
            colorbar(app.UIAxes)
            set(s,'EdgeColor','none')
            set(s,'FaceColor','interp')
            close(d)
        end
        
        function PlotElectronCloud(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            r = app.rmax*rand(1,app.points);
            phi = 2*pi*rand(1,app.points);
            theta = pi*rand(1,app.points);
            Psi = WaveFunction(app.n,app.l,app.m,app.Z,r,phi,theta);
            dV = r.*r.*sin(theta);
            P = conj(Psi).*Psi.*dV;
            P = P/max(P,[],"all");
            compare = rand(1,app.points);
            index = find(compare<P);
            r = r(index);
            phi = phi(index);
            theta = theta(index);
            [x,y,z] = sph2cart(phi, pi/2-theta, r);
            scatter3(app.UIAxes,x,y,z,0.5,'k','filled')
            axis(app.UIAxes,'square')
            daspect(app.UIAxes,[1 1 1])
            xlim(app.UIAxes,[-app.rmax app.rmax])
            ylim(app.UIAxes,[-app.rmax app.rmax])
            zlim(app.UIAxes,[-app.rmax app.rmax])
            view(app.UIAxes,45,10)
            title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' m=',num2str(app.m),' Z=',num2str(app.Z),' 电子云图'])
            xlabel(app.UIAxes,'x/a_0')
            ylabel(app.UIAxes,'y/a_0')
            zlabel(app.UIAxes,'z/a_0')
            close(d)
        end
        
        function PlotSurface(app)
            d = uiprogressdlg(app.UIFigure,'Title','计算中...','Indeterminate','on');
            cla(app.UIAxes,'reset')
            if strcmp(app.rmaxmode,'Auto') && app.l == 0
                app.rmax = 2;
            end
            dim1 = linspace(-app.rmax,app.rmax,app.rpoints);
            if app.SliceCheckBox.Value
                dim2 = linspace(app.IntrEditField.Value,app.rmax,app.rpoints);
                if strcmp(app.AxesDropDown.Value,'x')
                    [x,y,z] = meshgrid(dim2,dim1,dim1);
                elseif strcmp(app.AxesDropDown.Value,'y')
                    [x,y,z] = meshgrid(dim1,dim2,dim1);
                else
                    [x,y,z] = meshgrid(dim1,dim1,dim2);
                end
            else
                [x,y,z] = meshgrid(dim1);
            end
            r = sqrt(x.^2+y.^2+z.^2);
            phi = atan2(y,x);
            theta = atan2(sqrt(x.^2+y.^2),z);
            Psi = WaveFunction(app.n,app.l,app.m,app.Z,r,phi,theta);
            P = conj(Psi).*Psi;
            P = P/max(P(:));
            s1 = isosurface(x,y,z,P,0.1);
            patch(app.UIAxes,s1,'FaceColor',[0 0 0.2],'EdgeColor','none');
            s2 = isosurface(x,y,z,P,0.2);
            patch(app.UIAxes,s2,'FaceColor',[0 0 0.4],'EdgeColor','none');
            s3 = isosurface(x,y,z,P,0.3);
            patch(app.UIAxes,s3,'FaceColor',[0 0 0.6],'EdgeColor','none');
            s4 = isosurface(x,y,z,P,0.4);
            patch(app.UIAxes,s4,'FaceColor',[0 0 0.8],'EdgeColor','none');
            s5 = isosurface(x,y,z,P,0.5);
            patch(app.UIAxes,s5,'FaceColor',[0 0 1],'EdgeColor','none'); 
            axis(app.UIAxes,'square')
            daspect(app.UIAxes,[1 1 1])
            alpha(app.UIAxes,0.1)
            camlight(app.UIAxes)
            lighting(app.UIAxes,'gouraud');
            view(app.UIAxes,3)
            title(app.UIAxes,['n=',num2str(app.n),' l=',num2str(app.l),' m=',num2str(app.m),' Z=',num2str(app.Z),' 等值面图'])
            xlabel(app.UIAxes,'x/a_0')
            ylabel(app.UIAxes,'y/a_0')
            zlabel(app.UIAxes,'z/a_0')
            close(d)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ScreenSize = get(0, 'ScreenSize');
            app.UIFigure.Position(1) = (ScreenSize(3)-app.UIFigure.Position(3))/2;
            app.UIFigure.Position(2) = (ScreenSize(4)-app.UIFigure.Position(4))/2;
        end

        % Value changed function: ImgTypeDropDown
        function ImgTypeDropDownValueChanged(app, event)
            if strcmp(app.ImgTypeDropDown.Value, '径向函数') || strcmp(app.ImgTypeDropDown.Value, '径向概率密度') || strcmp(app.ImgTypeDropDown.Value, '径向概率分布')
                app.AdvButton.Visible = 'on';
                app.AdvButton.Position = [55,90,100,24];
                app.ContourCheckBox.Visible = 'off';
                app.AxesDropDown.Visible = 'off';
                app.EqTextArea.Visible = 'off';
                app.IntrEditField.Visible = 'off';
                app.SliceCheckBox.Visible = 'off';
                AdvancedOptions(app,'Auto',0.01,1,100,10000)
            elseif strcmp(app.ImgTypeDropDown.Value, '球谐函数') || strcmp(app.ImgTypeDropDown.Value, '角向概率分布')
                app.AdvButton.Visible = 'off';
                app.ContourCheckBox.Visible = 'off';
                app.AxesDropDown.Visible = 'off';
                app.EqTextArea.Visible = 'off';
                app.IntrEditField.Visible = 'off';
                app.SliceCheckBox.Visible = 'off';
            elseif strcmp(app.ImgTypeDropDown.Value, '切面图')
                app.ContourCheckBox.Position = [19,91,63,22];
                app.ContourCheckBox.Visible = 'on';
                app.ContourCheckBox.Value = 0;
                app.AxesDropDown.Position = [19,54,50,22];
                app.EqTextArea.Position = [79,54,22,22];
                app.IntrEditField.Position = [111,54,80,22];
                app.AxesDropDown.Value = 'x';
                app.IntrEditField.Value = 0;
                app.AxesDropDown.Visible = 'on';
                app.EqTextArea.Visible = 'on';
                app.IntrEditField.Visible = 'on';
                app.AxesDropDown.Enable = 'on';
                app.IntrEditField.Enable = 'on';
                app.AdvButton.Position = [55,15,100,24];
                app.AdvButton.Visible = 'on';
                app.SliceCheckBox.Visible = 'off';
                AdvancedOptions(app,'Auto',0.1,1,100,10000)
            elseif strcmp(app.ImgTypeDropDown.Value, '电子云图')
                app.AdvButton.Visible = 'on';
                app.AdvButton.Position = [55,90,100,24];
                app.ContourCheckBox.Visible = 'off';
                app.AxesDropDown.Visible = 'off';
                app.EqTextArea.Visible = 'off';
                app.IntrEditField.Visible = 'off';
                app.SliceCheckBox.Visible = 'off';
                AdvancedOptions(app,'Auto',0.01,1,100,10000)
            else
                app.SliceCheckBox.Position = [19,91,63,22];
                app.SliceCheckBox.Visible = 'on';
                app.SliceCheckBox.Value = 0;
                app.AxesDropDown.Position = [19,54,50,22];
                app.EqTextArea.Position = [79,54,22,22];
                app.IntrEditField.Position = [111,54,80,22];
                app.AxesDropDown.Value = 'x';
                app.IntrEditField.Value = 0;
                app.AxesDropDown.Visible = 'on';
                app.EqTextArea.Visible = 'on';
                app.IntrEditField.Visible = 'on';
                app.AxesDropDown.Enable = 'off';
                app.IntrEditField.Enable = 'off';
                app.AdvButton.Position = [55,15,100,24];
                app.AdvButton.Visible = 'on';
                app.ContourCheckBox.Visible = 'off';
                AdvancedOptions(app,'Auto',0.1,1,100,10000)
            end
        end

        % Button pushed function: AdvButton
        function AdvButtonPushed(app, event)
            app.AdvButton.Enable = 'off';
            app.DialogApp = Advanced(app,app.rmaxmode,app.treshold,app.rmax,app.rpoints,app.points,app.ImgTypeDropDown.Value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.n = app.nSpinner.Value;
            app.l = app.lSpinner.Value;
            app.m = app.mSpinner.Value;
            app.Z = app.ZSpinner.Value;
            if strcmp(app.rmaxmode,'Auto')
                app.rmax = Autormax(app.n,app.l,app.Z,app.treshold);
            end
            if strcmp(app.ImgTypeDropDown.Value, '径向函数')
                PlotRadialFunction(app)
            elseif strcmp(app.ImgTypeDropDown.Value, '径向概率分布')
                PlotRadialProbability(app)
            elseif strcmp(app.ImgTypeDropDown.Value, '径向概率密度')
                PlotRadialDensity(app)
            elseif strcmp(app.ImgTypeDropDown.Value, '球谐函数')
                PlotSphericalHarmonics(app)
            elseif strcmp(app.ImgTypeDropDown.Value, '角向概率分布')
                PlotAngularProbability(app)
            elseif strcmp(app.ImgTypeDropDown.Value, '切面图')
                PlotSlice(app)
            elseif strcmp(app.ImgTypeDropDown.Value, '电子云图')
                PlotElectronCloud(app)
            else
                PlotSurface(app)
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file,location] = uiputfile({'*.jpg';'*.png'});
            if isequal(file,0) || isequal(location,0)
            else
                filename = strcat(location,file);
                exportgraphics(app.UIAxes,filename)
            end
        end

        % Value changed function: nSpinner
        function nSpinnerValueChanged(app, event)
            if app.nSpinner.Value < 1 
                uialert(app.UIFigure,'n必须为正整数！','Error')
                app.nSpinner.Value = 1;
            elseif app.nSpinner.Value ~= round(app.nSpinner.Value)
                uialert(app.UIFigure,'n必须为正整数！','Error')
                app.nSpinner.Value = round(app.nSpinner.Value);
            elseif app.nSpinner.Value < app.lSpinner.Value+1
                app.lSpinner.Value = app.nSpinner.Value-1;
                if app.lSpinner.Value < app.mSpinner.Value
                    app.mSpinner.Value = app.lSpinner.Value;
                elseif app.lSpinner.Value < -app.mSpinner.Value
                    app.mSpinner.Value = -app.lSpinner.Value;
                end
            end
            if strcmp(app.ImgTypeDropDown.Value,'切面图') || strcmp(app.ImgTypeDropDown.Value,'等值面图')
                AdvancedOptions(app,'Auto',0.1,1,100,10000)
            else
                AdvancedOptions(app,'Auto',0.01,1,100,10000)
            end
        end

        % Value changed function: lSpinner
        function lSpinnerValueChanged(app, event)
            AdvancedOptions(app,'Auto',app.treshold,1,50,10000)
            if app.lSpinner.Value < 0
                uialert(app.UIFigure,'0 <= l <= n-1，且为整数！','Error')
                app.lSpinner.Value = 0;
            elseif app.lSpinner.Value > app.nSpinner.Value-1
                uialert(app.UIFigure,'0 <= l <= n-1，且为整数！','Error')
                app.lSpinner.Value = app.nSpinner.Value-1;
            elseif app.lSpinner.Value ~= round(app.lSpinner.Value)
                uialert(app.UIFigure,'0 <= l <= n-1，且为整数！','Error')
                app.lSpinner.Value = round(app.lSpinner.Value);
            elseif app.lSpinner.Value < app.mSpinner.Value
                app.mSpinner.Value = app.lSpinner.Value;
            elseif app.lSpinner.Value < -app.mSpinner.Value
                app.mSpinner.Value = -app.lSpinner.Value;
            end
            if strcmp(app.ImgTypeDropDown.Value,'切面图') || strcmp(app.ImgTypeDropDown.Value,'等值面图')
                AdvancedOptions(app,'Auto',0.1,1,100,10000)
            else
                AdvancedOptions(app,'Auto',0.01,1,100,10000)
            end
        end

        % Value changed function: mSpinner
        function mSpinnerValueChanged(app, event)
            AdvancedOptions(app,'Auto',app.treshold,1,50,10000)
            if app.mSpinner.Value < -app.lSpinner.Value
                uialert(app.UIFigure,'-l <= m <= l，且为整数！','Error')
                app.mSpinner.Value = -app.lSpinner.Value;
            elseif app.mSpinner.Value > app.lSpinner.Value
                uialert(app.UIFigure,'-l <= m <= l，且为整数！','Error')
                app.mSpinner.Value = app.lSpinner.Value;
            elseif app.mSpinner.Value ~= round(app.mSpinner.Value)
                uialert(app.UIFigure,'-l <= m <= l，且为整数！','Error')
                app.mSpinner.Value = round(app.mSpinner.Value);
            end
            if strcmp(app.ImgTypeDropDown.Value,'切面图') || strcmp(app.ImgTypeDropDown.Value,'等值面图')
                AdvancedOptions(app,'Auto',0.1,1,100,10000)
            else
                AdvancedOptions(app,'Auto',0.01,1,100,10000)
            end
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            AdvancedOptions(app,'Auto',app.treshold,1,50,10000)
            if app.ZSpinner.Value < 1 
                uialert(app.UIFigure,'Z必须为正整数！','Error')
                app.ZSpinner.Value = 1;
            elseif app.ZSpinner.Value ~= round(app.ZSpinner.Value)
                uialert(app.UIFigure,'Z必须为正整数！','Error')
                app.ZSpinner.Value = round(app.ZSpinner.Value);
            end
            if strcmp(app.ImgTypeDropDown.Value,'切面图') || strcmp(app.ImgTypeDropDown.Value,'等值面图')
                AdvancedOptions(app,'Auto',0.1,1,100,10000)
            else
                AdvancedOptions(app,'Auto',0.01,1,100,10000)
            end
        end

        % Value changed function: SliceCheckBox
        function SliceCheckBoxValueChanged(app, event)
            if app.SliceCheckBox.Value
                app.AxesDropDown.Enable = 'on';
                app.IntrEditField.Enable = 'on';
            else
                app.AxesDropDown.Enable = 'off';
                app.IntrEditField.Enable = 'off';
            end
        end

        % Value changed function: IntrEditField
        function IntrEditFieldValueChanged(app, event)
            if strcmp(app.ImgTypeDropDown.Value, '等值面图')
                if app.lSpinner.Value == 0
                    app.rmax = 2;
                else
                    app.rmax = Autormax(app.nSpinner.Value,app.lSpinner.Value,app.ZSpinner.Value,0.1);
                end
                if app.IntrEditField.Value < -app.rmax
                    uialert(app.UIFigure,['截距必须在',num2str(-app.rmax),'到',num2str(app.rmax),'之间'],'Error')
                    app.IntrEditField.Value = -app.rmax;
                elseif app.IntrEditField.Value > app.rmax
                    uialert(app.UIFigure,['截距必须在',num2str(-app.rmax),'到',num2str(app.rmax),'之间'],'Error')
                    app.IntrEditField.Value = app.rmax;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.ImgPanel.Layout.Row = 2;
                app.ImgPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.ImgPanel.Layout.Row = 1;
                app.ImgPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ParamPanel = uipanel(app.GridLayout);
            app.ParamPanel.Title = '参数';
            app.PlotButton = uibutton(app.ParamPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.OptParamPanel = uipanel(app.ParamPanel);
            app.OptParamPanel.Title = '可选参数';
            app.AdvButton = uibutton(app.OptParamPanel, 'push');
            app.AdvButton.ButtonPushedFcn = createCallbackFcn(app, @AdvButtonPushed, true);
            app.AdvButton.Text = '高级';
            app.ContourCheckBox = uicheckbox(app.OptParamPanel);
            app.ContourCheckBox.Visible = 'off';
            app.ContourCheckBox.Text = '等值线';
            app.SliceCheckBox = uicheckbox(app.OptParamPanel);
            app.SliceCheckBox.ValueChangedFcn = createCallbackFcn(app, @SliceCheckBoxValueChanged, true);
            app.SliceCheckBox.Visible = 'off';
            app.SliceCheckBox.Text = '切面';
            app.AxesDropDown = uidropdown(app.OptParamPanel);
            app.AxesDropDown.Items = {'x', 'y', 'z'};
            app.AxesDropDown.Visible = 'off';
            app.AxesDropDown.Value = 'x';
            app.EqTextArea = uitextarea(app.OptParamPanel);
            app.EqTextArea.Editable = 'off';
            app.EqTextArea.Visible = 'off';
            app.EqTextArea.Value = {'='};
            app.IntrEditField = uieditfield(app.OptParamPanel, 'numeric');
            app.IntrEditField.ValueChangedFcn = createCallbackFcn(app, @IntrEditFieldValueChanged, true);
            app.IntrEditField.Visible = 'off';
            app.BasicParamPanel = uipanel(app.ParamPanel);
            app.BasicParamPanel.Title = '基本参数';
            app.ZSpinnerLabel = uilabel(app.BasicParamPanel);
            app.ZSpinnerLabel.Tooltip = {'核电核数'};
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.BasicParamPanel);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Tooltip = {'Z = 1, 2, ...'};
            app.ZSpinner.Value = 1;
            app.mSpinnerLabel = uilabel(app.BasicParamPanel);
            app.mSpinnerLabel.Tooltip = {'磁量子数'};
            app.mSpinnerLabel.Text = 'm';
            app.mSpinner = uispinner(app.BasicParamPanel);
            app.mSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.mSpinner.Tooltip = {'m = -l, -l+1, ... , l-1, l'};
            app.lSpinnerLabel = uilabel(app.BasicParamPanel);
            app.lSpinnerLabel.Tooltip = {'角量子数'};
            app.lSpinnerLabel.Text = 'l';
            app.lSpinner = uispinner(app.BasicParamPanel);
            app.lSpinner.ValueChangedFcn = createCallbackFcn(app, @lSpinnerValueChanged, true);
            app.lSpinner.Tooltip = {'l = 0, 1, ... , n-1'};
            app.nSpinnerLabel = uilabel(app.BasicParamPanel);
            app.nSpinnerLabel.Tooltip = {'主量子数'};
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.BasicParamPanel);
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @nSpinnerValueChanged, true);
            app.nSpinner.Tooltip = {'n = 1, 2, ...'};
            app.nSpinner.Value = 1;
            app.Label = uilabel(app.BasicParamPanel);
            app.Label.Text = '图像类型';
            app.ImgTypeDropDown = uidropdown(app.BasicParamPanel);
            app.ImgTypeDropDown.Items = {'径向函数', '径向概率分布', '径向概率密度', '球谐函数', '角向概率分布', '切面图', '电子云图', '等值面图'};
            app.ImgTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImgTypeDropDownValueChanged, true);
            app.ImgTypeDropDown.Value = '径向函数';
            app.ImgPanel = uipanel(app.GridLayout);
            app.ImgPanel.Title = '图像';
            app.UIAxes = uiaxes(app.ImgPanel);
            app.SaveButton = uibutton(app.ImgPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hygrogen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end